classdef UnitConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UnitConversionProgramLabel    matlab.ui.control.Label
        ConvertDropDownLabel          matlab.ui.control.Label
        ConvertDropDown               matlab.ui.control.DropDown
        ConversionDropDownLabel       matlab.ui.control.Label
        ConversionDropDown            matlab.ui.control.DropDown
        ValueToConvertEditFieldLabel  matlab.ui.control.Label
        ValueToConvertEditField       matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        ConvertButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
         
        conversionSelection % Description
    end
    
    methods (Access = private)
        
        function out = convert(app)
        x = app.ValueToConvertEditField.Value;
            
        switch (app.conversionSelection)
            case 1 %C to F
                cValue = x;
                out = (cValue*(9/5))+32;
            case 2 %F to C
               fValue = x;
               out = (fValue - 32)*(5/9);
            case 3 %cm to "
                cmValue = x;
                out = cmValue/2.54;
            case 4 %" to cm
                inchValue = x;
                out = inchValue * 2.54;
            case 5 %m 5o ft
                mValue = x;
                out = mValue/0.3048;
            case 6 %ft to m
                ftValue = x;
                out = ftValue * 0.3048;
            case 7 %km to mi
                kmValue = x;
                out = kmValue/1.609344;
            case 8 %mi to km
                 milesValue = x;
                 out = milesValue * 1.609344;
            case 9 %g to ounce
                 gramsValue = x;
                 out = gramsValue / 28.3495;
            case 10 %ounce to g
                ouncesValue = x;
                out = ouncesValue * 28.3495;
            case 11 %kg to pound
                kgValue = x;
                out = kgValue / 0.4536;
            case 12 %pound to kg
                poundsValue = x;
                out = poundsValue * 0.4536;
            case 13 %kmph to mph
                kmphValue = x;
                out = kmphValue / 1.609344;
            case 14 %mph to kmph
                mphValue = x;
                out = mphValue * 1.609344;
            case 15 %l to ga
                litreValue = x;
                out = litreValue / 3.78544;
            case 16 %ga to l
                gallonValue = x;
                out = gallonValue * 3.78544;
            case 17 %ha to ac
                hectareValue = x;
                out = hectareValue * 2.4711;
            case 18 %ac to ha
                acreValue = x;
                out = acreValue / 2.4711;
        end
        if (x < 0 && app.conversionSelection ~= 1 && app.conversionSelection ~= 2)
            out = 'false';
        end    
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConversionDropDown.Items = {'Celsius to Fahrenheit', 'Fahrenheit to Celsius'};
            app.ConvertDropDown.Items = {'Temperature','Length','Mass','Speed','Volume','Area'};
            app.conversionSelection = 1;
        end

        % Value changed function: ConversionDropDown
        function ConversionDropDownValueChanged(app, event)
            value2 = app.ConversionDropDown.Value;
            switch value2
                case 'Celsius to Fahrenheit'
                   app.conversionSelection = 1;
                case 'Fahrenheit to Celsius'
                    app.conversionSelection = 2;
                case 'Centimeters to Inches'
                    app.conversionSelection = 3;
                case 'Inches to Centimeters'
                    app.conversionSelection = 4;
                case 'Meters to Feet'
                    app.conversionSelection = 5;
                case 'Feet to Meters'
                    app.conversionSelection = 6;
                case 'Kilometers to Miles'
                    app.conversionSelection = 7;
                case 'Miles to Kilometers'
                    app.conversionSelection = 8;
                case 'Grams to Ounces'
                    app.conversionSelection = 9;
                case 'Ounces to Grams'
                    app.conversionSelection = 10;
                case 'Kilograms to Pounds'
                    app.conversionSelection = 11;
                case 'Pounds to Kilograms'
                    app.conversionSelection = 12;
                case 'Kilometers per Hour to Miles per Hour'
                    app.conversionSelection = 13;
                case 'Miles per Hour to Kilometers per Hour'
                    app.conversionSelection = 14;
                case 'Litres to Gallons'
                    app.conversionSelection = 15;
                case 'Gallons to Litres'
                    app.conversionSelection = 16;
                case 'Hectares to Acres'
                    app.conversionSelection = 17;
                case 'Acres to Hectares'
                    app.conversionSelection = 18;
            end
            
        end

        % Value changed function: ConvertDropDown
        function ConvertDropDownValueChanged(app, event)
            value = app.ConvertDropDown.Value;
            if strcmpi(value, 'Temperature')
                secondDropDown = 1;
            elseif strcmpi(value, 'Length')
                secondDropDown = 2;
            elseif strcmpi(value, 'Mass')
                secondDropDown = 3;
            elseif strcmpi(value, 'Speed')
                secondDropDown = 4;
            elseif strcmpi(value, 'Volume')
                secondDropDown = 5;
            elseif strcmpi(value, 'Area')
                secondDropDown = 6;
            end
            
           switch secondDropDown
               case 1
                  app.ConversionDropDown.Items = {'Celsius to Fahrenheit', 'Fahrenheit to Celsius'};
                case 2
                   app.ConversionDropDown.Items = {'Centimeters to Inches', 'Inches to Centimeters', 'Meters to Feet', 'Feet to Meters', 'Kilometers to Miles', 'Miles to Kilometers'};
                case 3
                   app.ConversionDropDown.Items = {'Grams to Ounces', 'Ounces to Grams', 'Kilograms to Pounds', 'Pounds to Kilograms'}; 
                case 4
                    app.ConversionDropDown.Items = {'Kilometers per Hour to Miles per Hour', 'Miles per Hour to Kilometers per Hour'};
                case 5
                    app.ConversionDropDown.Items = {'Litres to Gallons', 'Gallons to Litres'};
                case 6
                    app.ConversionDropDown.Items = {'Hectares to Acres', 'Acres to Hectares'};                
            end
        end

        % Value changed function: ValueToConvertEditField
        function ValueToConvertEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            convertVal = convert(app);
            if strcmp(convertVal,'false')
                convertVal = 'Invalid Negative Input';
            end    
            label = num2str(convertVal);   
            app.Label.Text = label;
            drawnow
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UnitConversionProgramLabel = uilabel(app.UIFigure);
            app.UnitConversionProgramLabel.Text = 'Unit Conversion Program';
            app.ConvertDropDownLabel = uilabel(app.UIFigure);
            app.ConvertDropDownLabel.Text = 'Unit Type';
            app.ConvertDropDown = uidropdown(app.UIFigure);
            app.ConvertDropDown.Items = {'Temperature', 'Length', 'Mass', 'Speed', 'Volume', 'Area'};
            app.ConvertDropDown.ValueChangedFcn = createCallbackFcn(app, @ConvertDropDownValueChanged, true);
            app.ConvertDropDown.Value = 'Temperature';
            app.ConversionDropDownLabel = uilabel(app.UIFigure);
            app.ConversionDropDownLabel.Text = 'Unit Direction';
            app.ConversionDropDown = uidropdown(app.UIFigure);
            app.ConversionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConversionDropDownValueChanged, true);
            app.ValueToConvertEditFieldLabel = uilabel(app.UIFigure);
            app.ValueToConvertEditFieldLabel.Text = 'Value To Convert';
            app.ValueToConvertEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueToConvertEditField.ValueChangedFcn = createCallbackFcn(app, @ValueToConvertEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UnitConverter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end